classdef AdromedaTeamGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure         % UI Figure
        LabelSlider matlab.ui.control.Label  % Slider
        Slider      matlab.ui.control.Slider % [0 100]
        UIAxes      matlab.ui.control.UIAxes % Title
    end


    properties (Access = public)
        % km/px
         km_px = 0.110;
         % Los datos estan organizados en arrays de structs 
           
            
          % Extrayendo y organizando las imagenes desde el folder img 
          files = sortByDate(dir ('./img/*png'));
            
          
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Valor que se le sustraerá a cada imagen
          substract = [150 130 250 250 220 180 220 240 240 200 180 240];
          temp = num2cell(substract);
          [app.files.substract] = temp{:};
          for i=1:length(app.files)
              % Lectura de imagen
              app.files(i).initialImg = imread(strcat('./img/',app.files(i).name));
              
              % Procesamiento de imagen
              app.files(i).substractedImg = MejoraImagen(app.files(i).initialImg,app.files(i).substract);
          end
            
           % Display image and stretch to fill axes
            imshow(app.files(1).initialImg,'Parent',app.UIAxes);
        end

        % Slider value changed function
        function SliderValueChanged(app)
            value = app.Slider.Value;
            plot(app.UIAxes,value*peaks)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = AdromedaTeamGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end